classdef ComparisonTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SongComparisonToolUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CompareButton                  matlab.ui.control.Button
        Song1Label                     matlab.ui.control.Label
        GetButton                      matlab.ui.control.Button
        Song2Label                     matlab.ui.control.Label
        GetButton_2                    matlab.ui.control.Button
        StartofComparisonEditFieldLabel  matlab.ui.control.Label
        StartofComparisonEditField     matlab.ui.control.EditField
        EndofComparisonEditFieldLabel  matlab.ui.control.Label
        EndofComparisonEditField       matlab.ui.control.EditField
        StartofComparisonEditField_2Label  matlab.ui.control.Label
        StartofComparisonEditField_2   matlab.ui.control.EditField
        EndofComparisonEditField_2Label  matlab.ui.control.Label
        EndofComparisonEditField_2     matlab.ui.control.EditField
        ErrorLabel                     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        TheDifferencecloserto0meansmoresimilarLabel  matlab.ui.control.Label
        LeftChannelDifferenceLabel     matlab.ui.control.Label
        RightChannelDifferenceLabel    matlab.ui.control.Label
        OverallDifferenceLabel         matlab.ui.control.Label
        LCResultLabel                  matlab.ui.control.Label
        RCResultLabel                  matlab.ui.control.Label
        OverallResultLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        path1 = ' ';
        path2 = ' ';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            %get the song from the user using uigetfile
            [file1, path] = uigetfile({'*.mp3'; '*.wav'; '*.flac'; '*.mp4'; '*.ogg' }, 'Song Selector');
            %it will return 0 if no file is selected, compares the path to
            %0
            if strcmp(string(file1), '0')
                %if it is, it will stop the function and ask the user to
                %select a song.
                app.ErrorLabel.Text = 'Please select a song to compare.';
                %escape the function
                return;
            else
                %this is the normal behaviour.
                app.Song1Label.Text = string(file1);
                app.path1 = append(path, string(file1));
                figure(app.SongComparisonToolUIFigure);
            end
            
        end

        % Button pushed function: GetButton_2
        function GetButton_2Pushed(app, event)
             %get the song from the user using uigetfile
            [file2, path] = uigetfile({'*.mp3'; '*.wav'; '*.flac'; '*.mp4'; '*.ogg'  }, 'Song Selector');
            %it will return 0 if no file is selected, compares the path to
            %0
            if strcmp(string(file2), '0')
                %if it is, it will stop the function and ask the user to
                %select a song.
                app.ErrorLabel.Text = 'Please select a song to compare.';
                %escape the function
                return;
            else
                %this is the normal behaviour.
                app.Song2Label.Text = string(file2);
                app.path2 = append(path, string(file2));
                figure(app.SongComparisonToolUIFigure);
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            %get the full length of the song to compare to start / end
            %times to make sure that nothing's going out of bounds.
            [fullY1, Fs] = audioread(app.path1);
            fullTimeY1 = length(fullY1)./Fs;
            
            %try converting the text into a workable time, will stop if it
            %cannot convert all the digits into the start point
            try
                sng1 = app.StartofComparisonEditField.Value;
                startSongOne = times(str2num(sng1(1)), 60);
                startSongOne = startSongOne + times(str2num(sng1(3)), 10);
                startSongOne = startSongOne + str2num(sng1(4));
            catch ME
                app.ErrorLabel.Text = 'Error in start of song one.'
                return;
            end
            
            %try converting the text into a workable time, will stop if it
            %cannot convert all the digits into the end point
            try 
                esng1 = app.EndofComparisonEditField.Value;
                endSongOne = times(str2num(esng1(1)), 60);
                endSongOne = endSongOne + times(str2num(esng1(3)), 10);
                endSongOne = endSongOne + str2num(esng1(4));
            catch ME
                app.ErrorLabel.Text = 'Error in end of song one.'
                return;
            end
            
            %checks if the start /  end point are beyond the song's length
            if (startSongOne > fullTimeY1) ||(endSongOne > fullTimeY1)
                app.ErrorLabel.Text = 'Given bounds for song one are out of bounds.'
                return;
            end
            %set the sample rate to 44,100 times a second (the industry
            %standard)
            Fs = 44100;
            
            %get a number that will let the function select a certain part
            %of the song.
            samplesSongOne = [startSongOne + 1 * Fs,endSongOne * Fs];
            
            %the same as the first song.
            [fullY2, Fs] = audioread(app.path1);
            fullTimeY2 = length(fullY1)./Fs;
            try
            sng2 = app.StartofComparisonEditField_2.Value;
            startSongTwo = times(str2num(sng2(1)), 60);
            startSongTwo = startSongTwo + times(str2num(sng2(3)), 10);
            startSongTwo = startSongTwo + str2num(sng2(4));
            catch ME
                app.ErrorLabel.Text = 'Error in start of song two.'
                return;
            end
            
            try
            esng2 = app.EndofComparisonEditField_2.Value;
            endSongTwo = times(str2num(esng2(1)), 60);
            endSongTwo = endSongTwo + times(str2num(esng2(3)), 10);
            endSongTwo = endSongTwo + str2num(esng2(4));
            catch ME
                app.ErrorLabel.Text = 'Error in end of song two.'
                return;
            end
            
            if (startSongTwo > fullTimeY2) ||(endSongTwo > fullTimeY2)
                app.ErrorLabel.Text = 'Given bounds for song two are out of bounds.'
                return;
            end
            
            samplesSongTwo = [startSongTwo + 1 * Fs,endSongTwo * Fs];
            
            %clears Fs out of the memory.
            clear Fs;


            %first song
            %returns an array of values for each audio channel, the path is
            %the path to the song, and the samples is what part of the song
            %it reads.
            [y] = audioread(app.path1, samplesSongOne);
            
            %second song
            [y2] = audioread(app.path2, samplesSongTwo);
            
            L1 = length(y);
            L2 = length(y2);
            %find shortest song, and set max of iterator to that.
            %it does this so that it won't go out of bounds when doing the
            %calculations.
            if L1 > L2
                maxnum = length(y2);
            elseif L1 < L2
                maxnum = length(y);
                
            elseif L1 == L2
                maxnum = length(y);
            else
                %last case scenario where something's broken, it'll output
                %to the user and escape the function.
                app.ErrorLabel.Tag = 'error with array lengths, check files';
                return;
            end
            
            %for comparing left and right audio channels
            diffLeft = 0;
            diffRight = 0;
            for i = 1:(maxnum -1)
                %apply the formula given in the report.
                diffLeft = diffLeft + (abs(y(i,1)-y2(i,1)).^2);
                diffRight = diffRight + (abs(y(i,2)-y2(i,2)).^2);
                
            end
            
            %find the average between the audio channels.
            diffLeft = (1/(maxnum)) .* diffLeft;
            
            diffRight = (1/(maxnum)) .* diffRight;
            %output the average.
            diff = (diffLeft + diffRight)/2;
            
            %put it onto the UI as both a text output and a graph.
            app.LCResultLabel.Text = string(diffLeft);
            app.RCResultLabel.Text = string(diffRight);
            app.OverallResultLabel.Text = string(diff);
            plot(app.UIAxes, y);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, y2);
            hold off;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SongComparisonToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {545, 545};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {410, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SongComparisonToolUIFigure = uifigure('Visible', 'off');
            app.SongComparisonToolUIFigure.Name = 'Song Comparison Tool';
            app.SongComparisonToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SongComparisonToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CompareButton = uibutton(app.LeftPanel, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.Song1Label = uilabel(app.LeftPanel);
            app.Song1Label.Text = 'Song 1';
            app.GetButton = uibutton(app.LeftPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.Song2Label = uilabel(app.LeftPanel);
            app.Song2Label.Text = 'Song 2';
            app.GetButton_2 = uibutton(app.LeftPanel, 'push');
            app.GetButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetButton_2Pushed, true);
            app.GetButton_2.Text = 'Get';
            app.StartofComparisonEditFieldLabel = uilabel(app.LeftPanel);
            app.StartofComparisonEditFieldLabel.Text = 'Start of Comparison';
            app.StartofComparisonEditField = uieditfield(app.LeftPanel, 'text');
            app.EndofComparisonEditFieldLabel = uilabel(app.LeftPanel);
            app.EndofComparisonEditFieldLabel.Text = 'End of Comparison';
            app.EndofComparisonEditField = uieditfield(app.LeftPanel, 'text');
            app.StartofComparisonEditField_2Label = uilabel(app.LeftPanel);
            app.StartofComparisonEditField_2Label.Text = 'Start of Comparison';
            app.StartofComparisonEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.EndofComparisonEditField_2Label = uilabel(app.LeftPanel);
            app.EndofComparisonEditField_2Label.Text = 'End of Comparison';
            app.EndofComparisonEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.ErrorLabel = uilabel(app.LeftPanel);
            app.ErrorLabel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Comparison of waveforms')
            xlabel(app.UIAxes, {'Time'; ''})
            ylabel(app.UIAxes, 'Difference')
            app.TheDifferencecloserto0meansmoresimilarLabel = uilabel(app.RightPanel);
            app.TheDifferencecloserto0meansmoresimilarLabel.Text = 'The Difference (closer to 0 means more similar)';
            app.LeftChannelDifferenceLabel = uilabel(app.RightPanel);
            app.LeftChannelDifferenceLabel.Text = 'Left Channel Difference';
            app.RightChannelDifferenceLabel = uilabel(app.RightPanel);
            app.RightChannelDifferenceLabel.Text = 'Right Channel Difference';
            app.OverallDifferenceLabel = uilabel(app.RightPanel);
            app.OverallDifferenceLabel.Text = 'Overall Difference';
            app.LCResultLabel = uilabel(app.RightPanel);
            app.LCResultLabel.Text = '';
            app.RCResultLabel = uilabel(app.RightPanel);
            app.RCResultLabel.Text = '';
            app.OverallResultLabel = uilabel(app.RightPanel);
            app.OverallResultLabel.Text = '';
            % Show the figure after all components are created
            app.SongComparisonToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComparisonTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SongComparisonToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SongComparisonToolUIFigure)
        end
    end
end